classdef CovidDataA01173839 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CovidDataA01173839UIFigure   matlab.ui.Figure
        SearchCountryEditField       matlab.ui.control.EditField
        SearchCountryEditFieldLabel  matlab.ui.control.Label
        EndDateDatePicker            matlab.ui.control.DatePicker
        EndDateDatePickerLabel       matlab.ui.control.Label
        StartDateDatePicker          matlab.ui.control.DatePicker
        StartDateDatePickerLabel     matlab.ui.control.Label
        SetDeafultSettingsButton     matlab.ui.control.Button
        OptionButtonGroup            matlab.ui.container.ButtonGroup
        DailyButton                  matlab.ui.control.RadioButton
        CumulativeButton             matlab.ui.control.RadioButton
        DataPlotButtonGroup          matlab.ui.container.ButtonGroup
        BothButton                   matlab.ui.control.RadioButton
        DeathsButton                 matlab.ui.control.RadioButton
        CasesButton                  matlab.ui.control.RadioButton
        AverageofdaysSlider          matlab.ui.control.Slider
        AverageofdaysSliderLabel     matlab.ui.control.Label
        StateorRegionListBox         matlab.ui.control.ListBox
        StateorRegionLabel           matlab.ui.control.Label
        CountryListBox               matlab.ui.control.ListBox
        CountryLabel                 matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        FullData
        AllCountries
        AllStatesorRegion 
        SelectedCountry
        ShowCases
        ShowDeaths
        ShowBoth
        SelectedStateorRegion
        NumberofCountries
        NumberofStateorRegion
        SetCumulative
        SetDaily
        SetAverageDays
        AverageDailyCases
        AverageDailyDeaths
        AverageCumulativeCases
        AverageCumulativeDeaths
        AllStatistics
        GlobalDailyCases
        GlobalDailyDeaths
        GlobalCumulativeCases
        GlobalCumulativeDeaths
        IndexCountries
        IndexState
        Dates
        StartDate
        EndDate
        TimePoints
        SearchingCountry


    end
    
    methods (Access = private)
        
        function SetData(app)
            app.AllCountries = unique(app.FullData(2:end,1));
            app.NumberofCountries = length(app.AllCountries);
            app.NumberofStateorRegion = app.NumberofCountries - sum(cellfun('isempty',app.FullData(2:end,2)));
            Numbers = cell2mat(app.AllStatistics(2:end,:));
            app.GlobalCumulativeCases = sum(Numbers(2:end,1:2:end));
            app.GlobalCumulativeDeaths = sum(Numbers(2:end,2:2:end));
            app.GlobalDailyCases = [app.GlobalCumulativeCases(1) diff(app.GlobalCumulativeCases)];
            app.GlobalDailyDeaths = [app.GlobalCumulativeDeaths(1) diff(app.GlobalCumulativeDeaths)];
            
        end
        
        function SetDefault(app)

            app.CountryListBox.Items = [{'Global'}; app.AllCountries];
            app.CountryListBox.Value = 'Global';
            scroll(app.CountryListBox, 'top');
            app.StateorRegionListBox.Items = {'All'};
            app.StateorRegionListBox.Value = 'All';
            app.CumulativeButton.Value = true;
            app.CasesButton.Value = true;
            app.DailyButton.Value = false;
            app.DeathsButton.Value = false;
            app.BothButton.Value = false;
            app.AverageofdaysSlider.Value = 1;
            app.StartDate = datenum(app.Dates{1},'mm/dd/yy');
            app.EndDate = datenum(app.Dates{end},'mm/dd/yy');
            app.StartDateDatePicker.Value = datetime(app.StartDate,"ConvertFrom","datenum");
            app.EndDateDatePicker.Value = datetime(app.EndDate,'ConvertFrom','datenum');
            app.StartDateDatePicker.Limits = [datetime(app.StartDate,"ConvertFrom","datenum") datetime(app.EndDate,'ConvertFrom','datenum')];
            app.EndDateDatePicker.Limits = app.StartDateDatePicker.Limits;
            app.SelectedCountry = app.CountryListBox.Value;
            app.SelectedStateorRegion = app.StateorRegionListBox.Value;
            app.SetAverageDays = app.AverageofdaysSlider.Value;
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.SetCumulative = app.CumulativeButton.Value;
            app.SetDaily = app.DailyButton.Value;
            app.SearchCountryEditField.Value = '';

            app.PlotData()

        end
        
        function PlotData(app)
            cla(app.UIAxes, 'reset');
            app.CalculateAverage();
            if ((app.ShowCases && ~app.ShowDeaths) && app.SetCumulative)
                bar(app.UIAxes, app.TimePoints, app.AverageCumulativeCases,'FaceColor', "#77AC30");
                xlabel(app.UIAxes, 'Dates',Interpreter='latex');
                ylabel(app.UIAxes, 'Number of Cases',Interpreter='latex');
            elseif ((app.ShowDeaths && ~app.ShowCases) && app.SetCumulative)
                plot(app.UIAxes,app.TimePoints,app.AverageCumulativeDeaths, Color = [0.8500 0.3250 0.0980]);
                xlabel(app.UIAxes, 'Dates',Interpreter='latex');
                ylabel(app.UIAxes, 'Number of Deaths',Interpreter='latex');
            elseif ((app.ShowCases && ~app.ShowDeaths) && app.SetDaily)
                bar(app.UIAxes,app.TimePoints,app.AverageDailyCases,'FaceColor', "#77AC30");
                xlabel(app.UIAxes, 'Dates',Interpreter='latex');
                ylabel(app.UIAxes, 'Number of Cases',Interpreter='latex');
            elseif ((app.ShowDeaths && ~app.ShowCases) && app.SetDaily)
                plot(app.UIAxes,app.TimePoints,app.AverageDailyDeaths,Color = [0.8500 0.3250 0.0980]);
                xlabel(app.UIAxes, 'Dates',Interpreter='latex');
                ylabel(app.UIAxes, 'Number of Deaths',Interpreter='latex');
            elseif (app.ShowBoth && app.SetCumulative)
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes,app.TimePoints,app.AverageCumulativeCases,'FaceColor', "#77AC30");
                app.UIAxes.YColor = [0.4660 0.6740 0.1880];
                ylabel(app.UIAxes, 'Number of Cases',Interpreter='latex');
                ytl = get(app.UIAxes, 'YTick');

                hold(app.UIAxes, 'on');

                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.TimePoints,app.AverageCumulativeDeaths,Color = [0.8500 0.3250 0.0980]);
                app.UIAxes.YColor = [0.8500 0.3250 0.0980];
                ylabel(app.UIAxes, 'Number of Deaths',Interpreter='latex');

                if (max(app.AverageDailyDeaths) < 20)
                    app.UIAxes.YTick = unique(round(app.UIAxes.YTick));
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             % Get original right tick values
                    ytrv = linspace(0, max(ytr), numel(ytl));   % Create new right tick values matching # left ticks
                    app.UIAxes.YTick = ytrv;                    % Set new right ticks
                    app.UIAxes.YTickLabels = round(ytrv);       % Set new right tick labels
                end
                
                hold(app.UIAxes, 'off');


            elseif (app.ShowBoth && app.SetDaily)
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes,app.TimePoints,app.AverageDailyCases,'FaceColor', "#77AC30");
                app.UIAxes.YColor = [0.4660 0.6740 0.1880];
                ylabel(app.UIAxes, 'Number of Cases',Interpreter='latex');
                ytl = get(app.UIAxes, 'YTick');

                hold(app.UIAxes, 'on');

                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.TimePoints,app.AverageDailyDeaths,Color = [0.8500 0.3250 0.0980]);
                app.UIAxes.YColor = [0.8500 0.3250 0.0980];
                ylabel(app.UIAxes, 'Number of Deaths',Interpreter='latex');

                if (max(app.AverageDailyDeaths) < 20)
                    app.UIAxes.YTick = unique(round(app.UIAxes.YTick));
                    app.UIAxes.YLim(1) = 0;
                else
                    ytr = get(app.UIAxes, 'YTick');             % Get original right tick values
                    ytrv = linspace(0, max(ytr), numel(ytl));   % Create new right tick values matching # left ticks
                    app.UIAxes.YTick = ytrv;                    % Set new right ticks
                    app.UIAxes.YTickLabels = round(ytrv);       % Set new right tick labels
                end
                
                hold(app.UIAxes, 'off');
            end

            set(app.UIAxes, 'FontName', 'Segoe UI', 'FontSize', 14);
            grid(app.UIAxes, 'on');
            box(app.UIAxes, 'off');
            
            % format date timepoints on x axis
            datetick(app.UIAxes, 'x', 'mm/dd');
            xlim(app.UIAxes, 'manual');
            xlim(app.UIAxes, [ app.StartDate app.EndDate ]);
            %xtickangle(app.UIAxes, 30);
            xlabel(app.UIAxes, 'Dates');
            
            % turn off scientic notations on y axes
            if app.ShowBoth
                app.UIAxes.YAxis(1).Exponent = 0;
                app.UIAxes.YAxis(2).Exponent = 0;
            else
                app.UIAxes.YAxis.Exponent = 0;
            end

            ytickformat(app.UIAxes, '%.0f');


            title(app.UIAxes, app.MakeTitle(), 'FontSize', 18,Interpreter='latex');


        end
        
        function CalculateAverage(app)
            if (app.SetAverageDays > 1)
                if isequal(app.SelectedCountry,'Global')
                    app.AverageDailyCases = movmean(app.GlobalDailyCases,app.SetAverageDays);
                    app.AverageDailyDeaths = movmean(app.GlobalDailyDeaths,app.SetAverageDays);
                    app.AverageCumulativeCases = movmean(app.GlobalCumulativeCases,app.SetAverageDays);
                    app.AverageCumulativeDeaths = movmean(app.GlobalCumulativeDeaths,app.SetAverageDays);
                else
                    app.IndexCountries = find(strcmp(app.SelectedCountry,app.FullData(:,1)));
                    if isequal(app.SelectedStateorRegion,'All')
                        app.IndexCountries = app.IndexCountries(1);
                        CountryData = cell2mat(app.FullData(app.IndexCountries,3:end));
                        CumulativeCases = CountryData(1:2:end);
                        CumulativeDeaths = CountryData(2:2:end);
                        app.AverageCumulativeCases = movmean(CumulativeCases,app.SetAverageDays);
                        app.AverageCumulativeDeaths = movmean(CumulativeDeaths,app.SetAverageDays);
                        app.AverageDailyCases = movmean([app.AverageCumulativeCases(1) diff(app.AverageCumulativeCases)],app.SetAverageDays);
                        app.AverageDailyDeaths = movmean([app.AverageCumulativeDeaths(1) diff(app.AverageCumulativeDeaths)],app.SetAverageDays);
                        app.AverageDailyCases(app.AverageDailyCases < 0) = 0;
                        app.AverageDailyDeaths(app.AverageDailyDeaths < 0) = 0;
                        
                    else
                        app.IndexState = find(strcmp(app.SelectedStateorRegion,app.FullData(:,2)));
                        StateData = cell2mat(app.FullData(app.IndexState,3:end));
                        CumulativeCases = StateData(1:2:end);
                        CumulativeDeaths = StateData(2:2:end);
                        app.AverageCumulativeCases = movmean(CumulativeCases,app.SetAverageDays);
                        app.AverageCumulativeDeaths = movmean(CumulativeDeaths,app.SetAverageDays);
                        app.AverageDailyCases = movmean([app.AverageCumulativeCases(1) diff(app.AverageCumulativeCases)],app.SetAverageDays);
                        app.AverageDailyDeaths = movmean([app.AverageCumulativeDeaths(1) diff(app.AverageCumulativeDeaths)],app.SetAverageDays);
                        app.AverageDailyCases(app.AverageDailyCases < 0) = 0;
                        app.AverageDailyDeaths(app.AverageDailyDeaths < 0) = 0;
                    

                    end
                end
            else
                if isequal(app.SelectedCountry,'Global')
                    app.AverageCumulativeCases = app.GlobalCumulativeCases;
                    app.AverageCumulativeDeaths = app.GlobalCumulativeDeaths;
                    app.AverageDailyCases = app.GlobalDailyCases;
                    app.AverageDailyDeaths = app.GlobalDailyDeaths;
                else
                    app.IndexCountries = find(strcmp(app.SelectedCountry,app.FullData(:,1)));
                    if isequal(app.SelectedStateorRegion,'All')
                        app.IndexCountries = app.IndexCountries(1);
                        CountryData = cell2mat(app.FullData(app.IndexCountries,3:end));
                        CumulativeCases = CountryData(1:2:end);
                        CumulativeDeaths = CountryData(2:2:end);
                        app.AverageCumulativeCases = movmean(CumulativeCases,app.SetAverageDays);
                        app.AverageCumulativeDeaths = movmean(CumulativeDeaths,app.SetAverageDays);
                        app.AverageDailyCases = movmean([app.AverageCumulativeCases(1) diff(app.AverageCumulativeCases)],app.SetAverageDays);
                        app.AverageDailyDeaths = movmean([app.AverageCumulativeDeaths(1) diff(app.AverageCumulativeDeaths)],app.SetAverageDays);
                        app.AverageDailyCases(app.AverageDailyCases < 0) = 0;
                        app.AverageDailyDeaths(app.AverageDailyDeaths < 0) = 0;
                    else
                        app.IndexState = find(strcmp(app.SelectedStateorRegion,app.FullData(:,2)));
                        StateData = cell2mat(app.FullData(app.IndexState,3:end));
                        CumulativeCases = StateData(1:2:end);
                        CumulativeDeaths = StateData(2:2:end);
                        app.AverageCumulativeCases = movmean(CumulativeCases,app.SetAverageDays);
                        app.AverageCumulativeDeaths = movmean(CumulativeDeaths,app.SetAverageDays);
                        app.AverageDailyCases = movmean([app.AverageCumulativeCases(1) diff(app.AverageCumulativeCases)],app.SetAverageDays);
                        app.AverageDailyDeaths = movmean([app.AverageCumulativeDeaths(1) diff(app.AverageCumulativeDeaths)],app.SetAverageDays);
                        app.AverageDailyCases(app.AverageDailyCases < 0) = 0;
                        app.AverageDailyDeaths(app.AverageDailyDeaths < 0) = 0;
                    end
                end

            end
            
        end
        
        function LabelDates(app)
            app.Dates = app.FullData(1,3:end);
            app.StartDate = datenum(app.Dates{1},'mm/dd/yy');
            app.EndDate = datenum(app.Dates{end},'mm/dd/yy');
            app.TimePoints = linspace(app.StartDate,app.EndDate,length(app.Dates));
        end


        function Title = MakeTitle(app)
            if app.SetCumulative
                ChartFrequency = 'Cumulative';
            else
                ChartFrequency = 'Daily';
            end
            if (app.ShowCases && ~app.ShowDeaths)
                ChartDataType = 'Cases';
            elseif (app.ShowDeaths && ~app.ShowCases)
                ChartDataType = 'Deaths';
            else
                ChartDataType = 'Cases and Deaths';
            end
            if isequal(app.SelectedCountry, 'Global')
                ChartName = 'Globally';
            elseif isequal(app.SelectedStateorRegion, 'All')
                ChartName = [ 'in ', app.SelectedCountry ];
            else
                ChartName = [ 'in ', app.SelectedStateorRegion ];
            end

            if (app.SetAverageDays > 1)
                ChartDaysMean = [ ' (', num2str(app.SetAverageDays), '-day mean)' ];
            else
                ChartDaysMean = '';
            end

            Title = [ ChartFrequency, ' Number of ', ChartDataType, ' ', ChartName, ChartDaysMean ];               
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.FullData = covid_data;
            app.AllStatistics = covid_data(:,3:end);
            SetData(app);
            LabelDates(app);
            SetDefault(app);


        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.SelectedCountry = app.CountryListBox.Value;
            Search = strcmp(app.SelectedCountry,app.FullData(:,1));
            Position = find(Search);
            app.AllStatesorRegion = app.FullData(Position,2);
            app.AllStatesorRegion(1,1) = {'All'};
            app.StateorRegionListBox.Items = app.AllStatesorRegion; %Check
            app.SelectedStateorRegion = 'All';
            app.PlotData()
            
        end

        % Value changed function: StateorRegionListBox
        function StateorRegionListBoxValueChanged(app, event)
            app.SelectedStateorRegion = app.StateorRegionListBox.Value;
            app.PlotData()
            
        end

        % Selection changed function: DataPlotButtonGroup
        function DataPlotButtonGroupSelectionChanged(app, event)
            app.ShowCases = app.CasesButton.Value || app.BothButton.Value;
            app.ShowDeaths = app.DeathsButton.Value || app.BothButton.Value;
            app.ShowBoth = app.BothButton.Value;
            app.PlotData()

            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.SetCumulative = app.CumulativeButton.Value;
            app.SetDaily = app.DailyButton.Value;
            
            app.PlotData()
            
        end

        % Value changed function: AverageofdaysSlider
        function AverageofdaysSliderValueChanged(app, event)
            [~,minIdx] = min(abs(app.AverageofdaysSlider.Value - app.AverageofdaysSlider.MajorTicks(:)));
            app.AverageofdaysSlider.Value = app.AverageofdaysSlider.MajorTicks(minIdx);
            app.SetAverageDays = round(app.AverageofdaysSlider.Value);
            app.PlotData()
        end

        % Button pushed function: SetDeafultSettingsButton
        function SetDeafultSettingsButtonPushed(app, event)
            app.SetDefault()
            
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            app.StartDate = datenum(app.StartDateDatePicker.Value);
            if app.StartDate >= app.EndDate
                app.EndDate = min([app.StartDate+1, datenum(app.EndDateDatePicker.Limits(2))]);
                app.EndDateDatePicker.Value = datetime(app.EndDate,'ConvertFrom','datenum');
                app.StartDate = app.EndDate - 1;
                app.StartDateDatePicker.Value = datetime(app.StartDate,"ConvertFrom","datenum");


            end
            app.PlotData()
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            app.EndDate = datenum(app.EndDateDatePicker.Value);
            if app.EndDate <= app.StartDate
                app.StartDate = min([app.EndDate-1, datenum(app.StartDateDatePicker.Limits(1))]);
                app.StartDateDatePicker.Value = datetime(app.StartDate,'ConvertFrom','datenum');
                app.EndDate = app.StartDate + 1;
                app.EndDateDatePicker.Value = datetime(app.EndDate,"ConvertFrom","datenum");


            end
            app.PlotData();
        
        end

        % Value changing function: SearchCountryEditField
        function SearchCountryEditFieldValueChanging(app, event)
            app.SearchingCountry = event.Value;
            if (app.SearchingCountry == "")
                app.SetDefault()
            else 
                CountriesList = [{'Global'}; app.AllCountries];
                Match = startsWith(upper(CountriesList),upper(app.SearchingCountry));
                app.CountryListBox.Items = CountriesList(Match);
                if sum(Match) == 1
                    app.SelectedCountry = CountriesList{Match};
                end
               
            
            end
            app.PlotData()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CovidDataA01173839UIFigure = uifigure('Visible', 'off');
            app.CovidDataA01173839UIFigure.Name = 'CovidDataA01173839';
            app.UIAxes = uiaxes(app.CovidDataA01173839UIFigure);
            title(app.UIAxes, 'Daily Number of Cases and Deaths')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountryLabel = uilabel(app.CovidDataA01173839UIFigure);
            app.CountryLabel.Interpreter = 'latex';
            app.CountryLabel.Text = 'Country:';
            app.CountryListBox = uilistbox(app.CovidDataA01173839UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateorRegionLabel = uilabel(app.CovidDataA01173839UIFigure);
            app.StateorRegionLabel.Interpreter = 'latex';
            app.StateorRegionLabel.Text = 'State or Region:';
            app.StateorRegionListBox = uilistbox(app.CovidDataA01173839UIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.AverageofdaysSliderLabel = uilabel(app.CovidDataA01173839UIFigure);
            app.AverageofdaysSliderLabel.Interpreter = 'latex';
            app.AverageofdaysSliderLabel.Text = {'Average'; '# of days'};
            app.AverageofdaysSlider = uislider(app.CovidDataA01173839UIFigure);
            app.AverageofdaysSlider.Limits = [1 15];
            app.AverageofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @AverageofdaysSliderValueChanged, true);
            app.AverageofdaysSlider.Value = 1;
            app.DataPlotButtonGroup = uibuttongroup(app.CovidDataA01173839UIFigure);
            app.DataPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataPlotButtonGroupSelectionChanged, true);
            app.DataPlotButtonGroup.Title = 'Data Plot';
            app.CasesButton = uiradiobutton(app.DataPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DataPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DataPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.CovidDataA01173839UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.SetDeafultSettingsButton = uibutton(app.CovidDataA01173839UIFigure, 'push');
            app.SetDeafultSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SetDeafultSettingsButtonPushed, true);
            app.SetDeafultSettingsButton.Text = 'Set Deafult Settings';
            app.StartDateDatePickerLabel = uilabel(app.CovidDataA01173839UIFigure);
            app.StartDateDatePickerLabel.Interpreter = 'latex';
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.CovidDataA01173839UIFigure);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.EndDateDatePickerLabel = uilabel(app.CovidDataA01173839UIFigure);
            app.EndDateDatePickerLabel.Interpreter = 'latex';
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.CovidDataA01173839UIFigure);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.SearchCountryEditFieldLabel = uilabel(app.CovidDataA01173839UIFigure);
            app.SearchCountryEditFieldLabel.Interpreter = 'latex';
            app.SearchCountryEditFieldLabel.Text = 'Search Country';
            app.SearchCountryEditField = uieditfield(app.CovidDataA01173839UIFigure, 'text');
            app.SearchCountryEditField.ValueChangingFcn = createCallbackFcn(app, @SearchCountryEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.CovidDataA01173839UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidDataA01173839

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CovidDataA01173839UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CovidDataA01173839UIFigure)
        end
    end
end